classdef LCMProjectImporter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExecutionPanel                  matlab.ui.container.Panel
        StartProcessingButton           matlab.ui.control.Button
        LoadProjectSettingsButton       matlab.ui.control.Button
        SaveProjectSettingsButton       matlab.ui.control.Button
        IOSettingsPanel                 matlab.ui.container.Panel
        SelectInputFilesButton          matlab.ui.control.Button
        SelectOutputFolderButton        matlab.ui.control.Button
        OutputFolderEditField           matlab.ui.control.EditField
        OutputFolderEditFieldLabel      matlab.ui.control.Label
        ExperimentrelatedParametersPanel  matlab.ui.container.Panel
        ReprocessingButtonGroup         matlab.ui.container.ButtonGroup
        NoneButton                      matlab.ui.control.RadioButton
        ReprocessTrackingOnlyButton     matlab.ui.control.RadioButton
        ReprocessSegandTraButton        matlab.ui.control.RadioButton
        ReprocessAllDetSegandTraButton  matlab.ui.control.RadioButton
        UITable                         matlab.ui.control.Table
        TimeAfterMAEditField            matlab.ui.control.NumericEditField
        TimeWindowAfterMitorisminLabel  matlab.ui.control.Label
        TimeBeforeMAEditField           matlab.ui.control.NumericEditField
        TimeWindowBeforeMitorisminEditFieldLabel  matlab.ui.control.Label
        TrackingParametersPanel         matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        UltrackTab                      matlab.ui.container.Tab
        MaxDistanceforLinkingmEditField  matlab.ui.control.NumericEditField
        MaxDistanceforLinkingmEditFieldLabel  matlab.ui.control.Label
        SeedClusteringTab               matlab.ui.container.Tab
        MeasureClusterCutoffButton      matlab.ui.control.Button
        ClusterCutoffRatioforTrackingmEditField  matlab.ui.control.NumericEditField
        ClusterCutoffRatioforTrackingmEditFieldLabel  matlab.ui.control.Label
        SegmentationPropagationTab      matlab.ui.container.Tab
        ForceNNLinkingCheckBox          matlab.ui.control.CheckBox
        MaxDistanceforForcedLinkingmEditField  matlab.ui.control.NumericEditField
        MaxDistanceforForcedLinkingmEditFieldLabel  matlab.ui.control.Label
        MeasureMaxDisplacementButton    matlab.ui.control.Button
        TrackingMethodDropDown          matlab.ui.control.DropDown
        TrackingMethodDropDownLabel     matlab.ui.control.Label
        SegmentationParametersPanel     matlab.ui.container.Panel
        CellposeCheckBox                matlab.ui.control.CheckBox
        LoGDetectorTWANGCheckBox        matlab.ui.control.CheckBox
        TabGroup2                       matlab.ui.container.TabGroup
        LoGDetectorTab                  matlab.ui.container.Tab
        MeasureMinMaxDiameterButton     matlab.ui.control.Button
        SeedDetStdDevEditField          matlab.ui.control.NumericEditField
        SeedDetStdDevEditFieldLabel     matlab.ui.control.Label
        PreviewLoGTWANGButton           matlab.ui.control.Button
        MaxNucleusDiametermEditField    matlab.ui.control.NumericEditField
        MaxNucleusDiametermEditFieldLabel  matlab.ui.control.Label
        MinNucleusDiametermEditField    matlab.ui.control.NumericEditField
        MinNucleusDiametermEditFieldLabel  matlab.ui.control.Label
        CellposeTab                     matlab.ui.container.Tab
        UseMATLABCellposeCheckBox       matlab.ui.control.CheckBox
        AverageNucleusDiameterinPixelEditField  matlab.ui.control.NumericEditField
        AverageNucleusDiameterinPixelEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        InputFolders % Description
        DataStorage = -1;
        LCMPath
    end
    
    methods (Access = private)
        
        function [previewImage, previewFolder, previewSpacing, previewFrame] = SelectPreviewImage(app)

            if (isempty(app.UITable.Data))
                errordlg('Please select some input folders first!', 'Missing Input Folders');
                previewImage = '';
                previewSpacing = 1.0;
                previewFolder = '';
                previewFrame = 1;

                return;
            end

            % if (isempty(app.UITable.Selection))
            %     errordlg('Please select a row to preview in the input folder table!', 'Missing Preview Selection');
            %     previewImage = '';
            %     previewSpacing = 1.0;
            %     previewFolder = '';
            %     previewFrame = 1;
            % 
            %     return;
            % end

            if (isempty(app.UITable.Selection))
                selectedRow = 1;
            else
                selectedRow = app.UITable.Selection(1, 1);
            end

            
            selectedExperiment = app.UITable.Data{selectedRow, 1};
            selectedSpacing = app.UITable.Data{selectedRow, 3};
            selectedFileFilter = app.UITable.Data{selectedRow, 4};
            selectedPreviewFile = app.UITable.Data{selectedRow, end};
            if (isempty(selectedPreviewFile))
                selectedPreviewFile = 1;
            end

            selectedFolder = '';
            for i=1:length(app.InputFolders)
                if contains(app.InputFolders{i}, selectedExperiment)
                    selectedFolder = app.InputFolders{i};
                    break;
                end
            end

            inputFiles = dir([selectedFolder, '*' selectedFileFilter '*.tif']);

            if (isempty(inputFiles))
                errordlg(sprintf('No tif files found in the selected input folder %s!', selectedFolder), 'Missing Input Files');
                return;
            end


            previewImage = [inputFiles(selectedPreviewFile).folder filesep inputFiles(selectedPreviewFile).name];
            previewSpacing = selectedSpacing;
            previewFolder = selectedFolder;
            previewFrame = selectedPreviewFile;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataStorage)
            if nargin > 1
                app.DataStorage = dataStorage;
            end
        end

        % Button pushed function: SelectOutputFolderButton
        function SelectOutputFolderButtonPushed(app, event)
            outputFolder = uigetdir();

            app.OutputFolderEditField.Value = outputFolder;
        end

        % Button pushed function: SelectInputFilesButton
        function SelectInputFilesButtonPushed(app, event)

            if (isempty(app.LCMPath))
                app.LCMPath = fileparts(mfilename('fullpath'));
            end

            addpath([app.LCMPath filesep 'toolbox/']);
            inputRootFolders = uipickfiles();
            
            if (isempty(inputRootFolders) || (~iscell(inputRootFolders) && inputRootFolders == 0))
                disp('No input folder selected. Aborting ...');
                return;
            end
            
            %% recursively find the valid input paths
            [inputFolders, experimentList, positionList] = callback_livecellminer_get_valid_input_paths(inputRootFolders);

            app.InputFolders = inputFolders;

            for i=1:length(experimentList)
                app.UITable.Data{i,1} = experimentList{i};
                app.UITable.Data{i,2} = 3;
                app.UITable.Data{i,3} = 0.415;
                app.UITable.Data{i,4} = '';
                app.UITable.Data{i,5} = '';
                app.UITable.Data{i,6} = 1;
            end

        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            key = event.Key;
            
            if (strcmp(key, 'delete'))
                selectedRow = unique(app.UITable.Selection(:,1));
                tempData = app.UITable.Data;
                tempData(selectedRow, :) = [];
                app.InputFolders(selectedRow,:) = [];

                app.UITable.Data = tempData;
            end
        end

        % Button pushed function: SaveProjectSettingsButton
        function SaveProjectSettingsButtonPushed(app, event)

            if (isfield(app.DataStorage.dataArea, 'ConfigurationFile') && isfile(app.DataStorage.dataArea.ConfigurationFile))
                outputFileName = app.DataStorage.dataArea.ConfigurationFile;
            else
                currentTimeString = string(datetime('now','TimeZone','local','Format','yyyy_MM_dd'));
                [file,location,indx] = uiputfile('*.mat','Save Processing Parameters', sprintf('%s/%s_ProcessingParameters.mat', app.OutputFolderEditField.Value, currentTimeString));

                if (file == 0)
                    return;
                end

                outputFileName = [location file];
            end

            %% clear the temporary settings variable
            configuration = struct();

            %% specify what to reprocess
            if (app.ReprocessAllDetSegandTraButton.Value)
                configuration.reprocessDetection = true;
                configuration.reprocessCellpose = true;
                configuration.reprocessTracking = true;
            elseif (app.ReprocessSegandTraButton.Value)
                configuration.reprocessDetection = false;
                configuration.reprocessCellpose = true;
                configuration.reprocessTracking = true;
            elseif (app.ReprocessTrackingOnlyButton.Value)
                configuration.reprocessDetection = false;
                configuration.reprocessCellpose = false;
                configuration.reprocessTracking = true;
            else
                configuration.reprocessDetection = false;
                configuration.reprocessCellpose = false;
                configuration.reprocessTracking = false;
            end
       
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% project-specific parameters and selection of algrithms to use for processing
            configuration.useCellpose = app.CellposeCheckBox.Value; %% if enabled, the cellpose algorithm will be used for segmentation instead of the threshold/watershed default algorithm
            configuration.useMATLABCellpose = app.UseMATLABCellposeCheckBox.Value; %% if enabled, the MATLAB-based cellpose implementation will be used
            configuration.useTWANG = app.LoGDetectorTWANGCheckBox.Value;
            configuration.seedBasedDetection = app.LoGDetectorTWANGCheckBox.Value;             %% if enabled, LoGSSMP seeds will be used. Otherwise, cell pose segmentation results will be used.
            configuration.trackingMethod = app.TrackingMethodDropDown.Value;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% the following folder structure is assumed and used to later group the data:
            %% %EXPERIMENT%/%POSITION%/%IMAGEFILES%.tif
            configuration.inputFolders = app.InputFolders;
            configuration.outputRoot = app.OutputFolderEditField.Value;
            
            configuration.experimentParameters = app.UITable.Data;
            imageSpacing = 1.0;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% advanced parameters (usually no need for change)
            configuration.minutesBeforeMA = app.TimeBeforeMAEditField.Value;
            configuration.minutesAfterMA = app.TimeAfterMAEditField.Value;
            configuration.minNucleusDiameter = app.MinNucleusDiametermEditField.Value;
            configuration.maxNucleusDiameter = app.MaxNucleusDiametermEditField.Value;
            configuration.seedDetectionStdThreshold = app.SeedDetStdDevEditField.Value;
            configuration.diameterCellpose = app.AverageNucleusDiameterinPixelEditField.Value;  %% diameter parameter used for the cellpose segmentation
            configuration.clusterCutoff = app.ClusterCutoffRatioforTrackingmEditField.Value; %% cluster cutoff (-1 for automatic detection based on 0.5 * average nearest neighbor distance of the 8 nearest neighbors)
            configuration.forceNNLinking = app.ForceNNLinkingCheckBox.Value;
            configuration.maxRadius = app.MaxDistanceforForcedLinkingmEditField.Value;  %% maximum radius to search for neighboring cells during tracking

            save(outputFileName, 'configuration', '-mat');

            app.DataStorage.dataArea.ConfigurationFile = outputFileName;
        end

        % Button pushed function: PreviewLoGTWANGButton
        function PreviewLoGTWANGButtonPushed(app, event)

            [previewFile, ~, imageSpacing, ~] = SelectPreviewImage(app);

            if (~isfile(previewFile))
                msgbox(sprintf('ERROR: Selected preview file was not found. Tried with %s', previewFile));
                return;
            end

            logMinSigma = (0.5 * app.MinNucleusDiametermEditField.Value) / sqrt(2);
            logMaxSigma = (0.5 * app.MaxNucleusDiametermEditField.Value) / sqrt(2);
            seedDetextionThreshold = app.SeedDetStdDevEditField.Value;
            
            xpiwitPipeline = callback_livecellminer_create_xpiwit_pipeline(logMinSigma, logMaxSigma, seedDetextionThreshold, imageSpacing);

            if (isfile(previewFile))

                [~, file, ~] = fileparts(previewFile);
                outputFolder = tempdir;
                outputImage = [outputFolder 'item_0006_TwangSegmentation/' file '_TwangSegmentation_Out1.tif'];
                outputCSV = [outputFolder 'item_0006_TwangSegmentation/' file '_TwangSegmentation_RegionProps.csv'];

                prevDir = pwd;
                [XPIWITPath, ~] = callback_livecellminer_get_external_dependencies(); 
                        
                %% specify the XPIWIT command
                XPIWITCommand = [XPIWITPath ' --output "' outputFolder '" --input "0, ' previewFile ', 2, float" --xml "' xpiwitPipeline '" --seed 0 --lockfile off --subfolder "filterid, filtername" --outputformat "imagename, filtername" --end'];
                XPIWITCommand = strrep(XPIWITCommand, '\', '/');

                %% run the XPIWIT command
                system(XPIWITCommand);                

                fh = figure(2); clf;
                set(gcf, 'Units','normalized', 'OuterPosition', [0,0,1,1]);

                subplot(1,2,1);
                imagesc(imread(previewFile)); hold on;
                colormap gray;

                keyPoints = dlmread(outputCSV, ';', 1, 0);
                plot(keyPoints(:,3), keyPoints(:,4), '.r');

                subplot(1,2,2);
                imagesc(imread(outputImage));

                cd(prevDir);    
            end
        end

        % Button pushed function: LoadProjectSettingsButton
        function LoadProjectSettingsButtonPushed(app, event)
            [file, folder] = uigetfile({'*.mat'});
            projectFile = [folder file];

            if (any(file == 0) || ~isfile(projectFile))
                return;
            end

            %% load the project file
            load(projectFile);
            app.DataStorage.dataArea.ConfigurationFile = projectFile;
    
            if (~exist('configuration', 'var') && ~isstruct(configuration))
                return;
            end

             %% project-specific parameters and selection of algrithms to use for processing
            app.CellposeCheckBox.Value = configuration.useCellpose;

            if (isfield(configuration, 'useMATLABCellpose'))
                app.UseMATLABCellposeCheckBox.Value = configuration.useMATLABCellpose;
            end
            app.ReprocessAllDetSegandTraButton.Value = configuration.reprocessDetection && configuration.reprocessCellpose && configuration.reprocessTracking;
            app.ReprocessSegandTraButton.Value = ~configuration.reprocessDetection && configuration.reprocessCellpose && configuration.reprocessTracking;
            app.ReprocessTrackingOnlyButton.Value = ~configuration.reprocessDetection && ~configuration.reprocessCellpose && configuration.reprocessTracking;
            app.NoneButton.Value = ~configuration.reprocessDetection && ~configuration.reprocessCellpose && ~configuration.reprocessTracking;

            app.LoGDetectorTWANGCheckBox.Value = configuration.seedBasedDetection;
            app.TrackingMethodDropDown.Value = configuration.trackingMethod;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% the following folder structure is assumed and used to later group the data:
            %% %EXPERIMENT%/%POSITION%/%IMAGEFILES%.tif
            app.InputFolders = configuration.inputFolders;
            app.OutputFolderEditField.Value = configuration.outputRoot;
            
            app.UITable.Data = configuration.experimentParameters;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% advanced parameters (usually no need for change)
            app.TimeBeforeMAEditField.Value = configuration.minutesBeforeMA;
            app.TimeAfterMAEditField.Value = configuration.minutesAfterMA;
            app.MinNucleusDiametermEditField.Value = configuration.minNucleusDiameter;
            app.MaxNucleusDiametermEditField.Value = configuration.maxNucleusDiameter;
            app.SeedDetStdDevEditField.Value = configuration.seedDetectionStdThreshold;
            app.AverageNucleusDiameterinPixelEditField.Value = configuration.diameterCellpose;
            app.ClusterCutoffRatioforTrackingmEditField.Value = configuration.clusterCutoff;
            app.ForceNNLinkingCheckBox.Value = configuration.forceNNLinking;
            app.MaxDistanceforForcedLinkingmEditField.Value = configuration.maxRadius;
        end

        % Button pushed function: MeasureMinMaxDiameterButton
        function MeasureMinMaxDiameterButtonPushed(app, event)

            [previewFile, ~, imageSpacing, ~] = SelectPreviewImage(app);

            if (previewFile == "")
                return;
            end

            previewImage = imread(previewFile);

            fh = figure(2);
            set(gcf, 'Units','normalized', 'OuterPosition', [0,0,1,1]);
            imagesc(previewImage);
            axis equal; axis tight;

            title('Measure the diameter of one of the smaller nuclei');
            minDiameterROI = drawline;
            app.MinNucleusDiametermEditField.Value = imageSpacing * norm(minDiameterROI.Position(1,:) - minDiameterROI.Position(2,:));
            
            title('Measure the diameter of one of the larger nuclei');
            maxDiameterROI = drawline;
            app.MaxNucleusDiametermEditField.Value = imageSpacing * norm(maxDiameterROI.Position(1,:) - maxDiameterROI.Position(2,:));

            app.AverageNucleusDiameterinPixelEditField.Value = norm(maxDiameterROI.Position(1,:) - maxDiameterROI.Position(2,:));
            
            close(fh);

        end

        % Button pushed function: MeasureMaxDisplacementButton
        function MeasureMaxDisplacementButtonPushed(app, event)

            if (isempty(app.InputFolders))
                disp('No input folders selected. Select some inputs first, before previewing parameter values.');
                return;
            end

            [~, previewFolder, imageSpacing, ~] = SelectPreviewImage(app);

            previewFiles = dir([previewFolder '*.tif']);
            currImage = imadjust(imread([previewFolder previewFiles(1).name]));
            nextImage = imadjust(imread([previewFolder previewFiles(2).name]));
            colorImage = cat(3, currImage, nextImage, zeros(size(currImage)));

            fh = figure(2);
            set(gcf, 'Units','normalized', 'OuterPosition', [0,0,1,1]);
            imagesc(colorImage);

            title('Measure the largest displacement between two corresponding cells');
            maxDisplacement = drawline;

            if (isvalid(maxDisplacement))
                app.MaxDistanceforForcedLinkingmEditField.Value = imageSpacing * norm(maxDisplacement.Position(1,:) - maxDisplacement.Position(2,:));
                close(fh);
            else
                disp('No line was drawn, aborting...');
            end
        end

        % Button pushed function: MeasureClusterCutoffButton
        function MeasureClusterCutoffButtonPushed(app, event)

            if (isempty(app.InputFolders))
                disp('No input folders selected. Select some inputs first, before previewing parameter values.');
                return;
            end

            [previewFile, ~, imageSpacing, ~] = SelectPreviewImage(app);
            previewImage = imread(previewFile);

            fh = figure(2); 
            set(gcf, 'Units','normalized', 'OuterPosition', [0,0,1,1]);
            imagesc(previewImage);

            title('Measure the maximum radius in which detections (centroids) should be combined. \n Usu');
            clusterCutoffROI = drawline;

            if (isvalid(clusterCutoffROI))
                app.ClusterCutoffRatioforTrackingmEditField.Value = imageSpacing * norm(clusterCutoffROI.Position(1,:) - clusterCutoffROI.Position(2,:));
                close(fh);
            else
                disp('No line was drawn, aborting...');
            end
        end

        % Value changed function: TrackingMethodDropDown
        function TrackingMethodDropDownValueChanged(app, event)
            value = app.TrackingMethodDropDown.Value;

            for i=1:length(app.TabGroup.Children)
                if (strcmp(app.TabGroup.Children(i).Title, value))
                    app.TabGroup.set('SelectedTab', app.TabGroup.Children(i))
                end
            end
        end

        % Clicked callback: TrackingMethodDropDown
        function TrackingMethodDropDownClicked(app, event)
            item = event.InteractionInformation.Item;

            if (isempty(item))
                return;
            end

            app.TabGroup.set('SelectedTab', app.TabGroup.Children(item))
        end

        % Button pushed function: StartProcessingButton
        function StartProcessingButtonPushed(app, event)
            
            SaveProjectSettingsButtonPushed(app, event);

            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SegmentationParametersPanel = uipanel(app.UIFigure);
            app.SegmentationParametersPanel.Title = 'Segmentation Parameters';
            app.TabGroup2 = uitabgroup(app.SegmentationParametersPanel);
            app.LoGDetectorTab = uitab(app.TabGroup2);
            app.LoGDetectorTab.Title = 'LoG Detector';
            app.MinNucleusDiametermEditFieldLabel = uilabel(app.LoGDetectorTab);
            app.MinNucleusDiametermEditFieldLabel.Text = 'Min. Nucleus Diameter (µm)';
            app.MinNucleusDiametermEditField = uieditfield(app.LoGDetectorTab, 'numeric');
            app.MinNucleusDiametermEditField.Tooltip = {'Decrease this value if smaller nuclei are not detected. Increase if multiple detections are found per nucleus. Typical values are between 1 and 4.'};
            app.MinNucleusDiametermEditField.Value = 4;
            app.MaxNucleusDiametermEditFieldLabel = uilabel(app.LoGDetectorTab);
            app.MaxNucleusDiametermEditFieldLabel.Text = 'Max. Nucleus Diameter (µm)';
            app.MaxNucleusDiametermEditField = uieditfield(app.LoGDetectorTab, 'numeric');
            app.MaxNucleusDiametermEditField.Tooltip = {'Decrease if multiple nuclei are detected as one (undersegmentation). Should be larger than LoG Min. Sigma. Typical values are between 4 and 8.'};
            app.MaxNucleusDiametermEditField.Value = 6;
            app.PreviewLoGTWANGButton = uibutton(app.LoGDetectorTab, 'push');
            app.PreviewLoGTWANGButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewLoGTWANGButtonPushed, true);
            app.PreviewLoGTWANGButton.Text = 'Preview LoG/TWANG';
            app.SeedDetStdDevEditFieldLabel = uilabel(app.LoGDetectorTab);
            app.SeedDetStdDevEditFieldLabel.Text = 'Seed Det. Std. Dev.';
            app.SeedDetStdDevEditField = uieditfield(app.LoGDetectorTab, 'numeric');
            app.SeedDetStdDevEditField.Tooltip = {'LoG-based seed detection uses only seeds that exceed the mean intensity + x*stdDev, where x is this parameter. Default 2.0. Decrease to also detect dim detections.'};
            app.SeedDetStdDevEditField.Value = 1;
            app.MeasureMinMaxDiameterButton = uibutton(app.LoGDetectorTab, 'push');
            app.MeasureMinMaxDiameterButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureMinMaxDiameterButtonPushed, true);
            app.MeasureMinMaxDiameterButton.Text = 'Measure Min/Max Diameter';
            app.CellposeTab = uitab(app.TabGroup2);
            app.CellposeTab.Title = 'Cellpose';
            app.AverageNucleusDiameterinPixelEditFieldLabel = uilabel(app.CellposeTab);
            app.AverageNucleusDiameterinPixelEditFieldLabel.Text = 'Average Nucleus Diameter in Pixel';
            app.AverageNucleusDiameterinPixelEditField = uieditfield(app.CellposeTab, 'numeric');
            app.AverageNucleusDiameterinPixelEditField.Tooltip = {'Average cell diameter measured in pixels. Only used for the Cellpose-based segmentation. Set to -1 to automatically set it based on 0.5*(minNucDiam + maxNucDiam)'};
            app.AverageNucleusDiameterinPixelEditField.Value = 30;
            app.UseMATLABCellposeCheckBox = uicheckbox(app.CellposeTab);
            app.UseMATLABCellposeCheckBox.Text = 'Use MATLAB Cellpose';
            app.UseMATLABCellposeCheckBox.Value = true;
            app.LoGDetectorTWANGCheckBox = uicheckbox(app.SegmentationParametersPanel);
            app.LoGDetectorTWANGCheckBox.Text = 'LoG Detector + TWANG';
            app.LoGDetectorTWANGCheckBox.Value = true;
            app.CellposeCheckBox = uicheckbox(app.SegmentationParametersPanel);
            app.CellposeCheckBox.Text = 'Cellpose';
            app.TrackingParametersPanel = uipanel(app.UIFigure);
            app.TrackingParametersPanel.Title = 'Tracking Parameters';
            app.TrackingMethodDropDownLabel = uilabel(app.TrackingParametersPanel);
            app.TrackingMethodDropDownLabel.Text = 'Tracking Method';
            app.TrackingMethodDropDown = uidropdown(app.TrackingParametersPanel);
            app.TrackingMethodDropDown.Items = {'Ultrack', 'Seed Clustering', 'Segmentation Propagation'};
            app.TrackingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackingMethodDropDownValueChanged, true);
            app.TrackingMethodDropDown.ClickedFcn = createCallbackFcn(app, @TrackingMethodDropDownClicked, true);
            app.TrackingMethodDropDown.Value = 'Ultrack';
            app.TabGroup = uitabgroup(app.TrackingParametersPanel);
            app.UltrackTab = uitab(app.TabGroup);
            app.UltrackTab.Title = 'Ultrack';
            app.MaxDistanceforLinkingmEditFieldLabel = uilabel(app.UltrackTab);
            app.MaxDistanceforLinkingmEditFieldLabel.Text = 'Max. Distance for Linking (µm)';
            app.MaxDistanceforLinkingmEditField = uieditfield(app.UltrackTab, 'numeric');
            app.MaxDistanceforLinkingmEditField.Tooltip = {'Maximum radius to search for neighboring cells during tracking measured in microns.'};
            app.MaxDistanceforLinkingmEditField.Value = 50;
            app.SeedClusteringTab = uitab(app.TabGroup);
            app.SeedClusteringTab.Title = 'Seed Clustering';
            app.ClusterCutoffRatioforTrackingmEditFieldLabel = uilabel(app.SeedClusteringTab);
            app.ClusterCutoffRatioforTrackingmEditFieldLabel.Text = 'Cluster Cutoff Ratio for Tracking (µm)';
            app.ClusterCutoffRatioforTrackingmEditField = uieditfield(app.SeedClusteringTab, 'numeric');
            app.ClusterCutoffRatioforTrackingmEditField.Tooltip = {'Cluster Cutoff Radius for Tracking (manual: cell diameter in microns; auto: -1 for automatic detection based on 0.5 * average nearest neighbor distance of the 8 nearest neighbors)'};
            app.ClusterCutoffRatioforTrackingmEditField.Value = -1;
            app.MeasureClusterCutoffButton = uibutton(app.SeedClusteringTab, 'push');
            app.MeasureClusterCutoffButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureClusterCutoffButtonPushed, true);
            app.MeasureClusterCutoffButton.Text = 'Measure Cluster Cutoff';
            app.SegmentationPropagationTab = uitab(app.TabGroup);
            app.SegmentationPropagationTab.Title = 'Segmentation Propagation';
            app.MeasureMaxDisplacementButton = uibutton(app.SegmentationPropagationTab, 'push');
            app.MeasureMaxDisplacementButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureMaxDisplacementButtonPushed, true);
            app.MeasureMaxDisplacementButton.Text = 'Measure Max Displacement';
            app.MaxDistanceforForcedLinkingmEditFieldLabel = uilabel(app.SegmentationPropagationTab);
            app.MaxDistanceforForcedLinkingmEditFieldLabel.Text = 'Max. Distance for Forced Linking (µm)';
            app.MaxDistanceforForcedLinkingmEditField = uieditfield(app.SegmentationPropagationTab, 'numeric');
            app.MaxDistanceforForcedLinkingmEditField.Tooltip = {'Maximum radius to search for neighboring cells during tracking measured in microns.'};
            app.MaxDistanceforForcedLinkingmEditField.Value = 50;
            app.ForceNNLinkingCheckBox = uicheckbox(app.SegmentationPropagationTab);
            app.ForceNNLinkingCheckBox.Tooltip = {'If enabled, tracking is enforced by just linking to the closest nearest neighbor (if distance is below max. distance).'};
            app.ForceNNLinkingCheckBox.Text = 'Force NN-Linking';
            app.ExperimentrelatedParametersPanel = uipanel(app.UIFigure);
            app.ExperimentrelatedParametersPanel.Title = 'Experiment-related Parameters';
            app.TimeWindowBeforeMitorisminEditFieldLabel = uilabel(app.ExperimentrelatedParametersPanel);
            app.TimeWindowBeforeMitorisminEditFieldLabel.Text = 'Time Window Before Mitoris (min';
            app.TimeBeforeMAEditField = uieditfield(app.ExperimentrelatedParametersPanel, 'numeric');
            app.TimeBeforeMAEditField.Value = 90;
            app.TimeWindowAfterMitorisminLabel = uilabel(app.ExperimentrelatedParametersPanel);
            app.TimeWindowAfterMitorisminLabel.Text = 'Time Window After Mitoris (min)';
            app.TimeAfterMAEditField = uieditfield(app.ExperimentrelatedParametersPanel, 'numeric');
            app.TimeAfterMAEditField.Value = 100;
            app.UITable = uitable(app.ExperimentrelatedParametersPanel);
            app.UITable.ColumnName = {'Experiment'; 'dT (min)'; 'Spacing (µm)'; 'Chromatin Suffix'; '2nd Ch. Suffix'; 'Preview Image'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true true];
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.ReprocessingButtonGroup = uibuttongroup(app.ExperimentrelatedParametersPanel);
            app.ReprocessingButtonGroup.Title = 'Reprocessing';
            app.ReprocessAllDetSegandTraButton = uiradiobutton(app.ReprocessingButtonGroup);
            app.ReprocessAllDetSegandTraButton.Tooltip = {'Reprocess everything (includes detection,  segmentation and tracking). Caution: Previous results will be overwritten!'};
            app.ReprocessAllDetSegandTraButton.Text = 'Reprocess All (Det., Seg. and Tra.)';
            app.ReprocessSegandTraButton = uiradiobutton(app.ReprocessingButtonGroup);
            app.ReprocessSegandTraButton.Tooltip = {'Reprocess segmentation and tracking. Caution: Previous results will be overwritten!'};
            app.ReprocessSegandTraButton.Text = 'Reprocess Seg. and Tra.';
            app.ReprocessTrackingOnlyButton = uiradiobutton(app.ReprocessingButtonGroup);
            app.ReprocessTrackingOnlyButton.Tooltip = {'Reprocess tracking only. Caution: Previous results will be overwritten!'};
            app.ReprocessTrackingOnlyButton.Text = 'Reprocess Tracking Only';
            app.NoneButton = uiradiobutton(app.ReprocessingButtonGroup);
            app.NoneButton.Tooltip = {'Full pipeline will be executed if no prior data exists.'; 'Processing of detection, segmentation and tracking will be skipped if it already exists.'};
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.IOSettingsPanel = uipanel(app.UIFigure);
            app.IOSettingsPanel.Title = 'IO Settings';
            app.OutputFolderEditFieldLabel = uilabel(app.IOSettingsPanel);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder';
            app.OutputFolderEditField = uieditfield(app.IOSettingsPanel, 'text');
            app.SelectOutputFolderButton = uibutton(app.IOSettingsPanel, 'push');
            app.SelectOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolderButtonPushed, true);
            app.SelectOutputFolderButton.Text = 'Select Output Folder';
            app.SelectInputFilesButton = uibutton(app.IOSettingsPanel, 'push');
            app.SelectInputFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputFilesButtonPushed, true);
            app.SelectInputFilesButton.Text = 'Select Input Files';
            app.ExecutionPanel = uipanel(app.UIFigure);
            app.ExecutionPanel.Title = 'Execution';
            app.SaveProjectSettingsButton = uibutton(app.ExecutionPanel, 'push');
            app.SaveProjectSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProjectSettingsButtonPushed, true);
            app.SaveProjectSettingsButton.Text = 'Save Project Settings';
            app.LoadProjectSettingsButton = uibutton(app.ExecutionPanel, 'push');
            app.LoadProjectSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProjectSettingsButtonPushed, true);
            app.LoadProjectSettingsButton.Text = 'Load Project Settings';
            app.StartProcessingButton = uibutton(app.ExecutionPanel, 'push');
            app.StartProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.StartProcessingButton.Text = 'Start Processing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LCMProjectImporter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end